classdef ppt_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NumPPTGUIFigure               matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        DynamicalModel                matlab.ui.container.ButtonGroup
        SlugButton                    matlab.ui.control.RadioButton
        ModSlugButton                 matlab.ui.control.RadioButton
        ThrusterGeometry              matlab.ui.container.ButtonGroup
        ParallelPlateButton           matlab.ui.control.RadioButton
        CoaxialButton                 matlab.ui.control.RadioButton
        ClearButton                   matlab.ui.control.Button
        SolveButton                   matlab.ui.control.StateButton
        ResultsLabel                  matlab.ui.control.Label
        energy_plot                   matlab.ui.control.UIAxes
        current_plot                  matlab.ui.control.UIAxes
        vel_pos                       matlab.ui.control.UIAxes
        PreDefinedConfigLabel         matlab.ui.control.Label
        PPTExampleDropDownLabel       matlab.ui.control.Label
        PPTExample                    matlab.ui.control.DropDown
        ConstantValuesLabel           matlab.ui.control.Label
        gLabel                        matlab.ui.control.Label
        g                             matlab.ui.control.NumericEditField
        gammaEditFieldLabel           matlab.ui.control.Label
        gamma                         matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        R                             matlab.ui.control.NumericEditField
        V_criticEditFieldLabel        matlab.ui.control.Label
        V_critic                      matlab.ui.control.NumericEditField
        PlasmaPropertiesLabel         matlab.ui.control.Label
        TeEditFieldLabel              matlab.ui.control.Label
        Te                            matlab.ui.control.NumericEditField
        n_eEditFieldLabel             matlab.ui.control.Label
        n_e                           matlab.ui.control.NumericEditField
        muEditFieldLabel              matlab.ui.control.Label
        mu                            matlab.ui.control.NumericEditField
        NumericalOptionsLabel         matlab.ui.control.Label
        MaxStepEditFieldLabel         matlab.ui.control.Label
        MaxStep                       matlab.ui.control.NumericEditField
        t_finalEditFieldLabel         matlab.ui.control.Label
        t_final                       matlab.ui.control.NumericEditField
        AbsTolEditFieldLabel          matlab.ui.control.Label
        AbsTol                        matlab.ui.control.NumericEditField
        RelTolEditFieldLabel          matlab.ui.control.Label
        RelTol                        matlab.ui.control.NumericEditField
        NumericalMethodDropDownLabel  matlab.ui.control.Label
        NumericalMethod               matlab.ui.control.DropDown
        ElectricParameterLabel        matlab.ui.control.Label
        LcEditFieldLabel              matlab.ui.control.Label
        Lc                            matlab.ui.control.NumericEditField
        LpeEditFieldLabel             matlab.ui.control.Label
        Lpe                           matlab.ui.control.NumericEditField
        LeEditFieldLabel              matlab.ui.control.Label
        Le                            matlab.ui.control.NumericEditField
        RcEditFieldLabel              matlab.ui.control.Label
        Rc                            matlab.ui.control.NumericEditField
        ReEditFieldLabel              matlab.ui.control.Label
        Re                            matlab.ui.control.NumericEditField
        RpeEditFieldLabel             matlab.ui.control.Label
        Rpe                           matlab.ui.control.NumericEditField
        RpEditFieldLabel              matlab.ui.control.Label
        Rp                            matlab.ui.control.NumericEditField
        CEditFieldLabel               matlab.ui.control.Label
        C                             matlab.ui.control.NumericEditField
        V0EditFieldLabel              matlab.ui.control.Label
        V0                            matlab.ui.control.NumericEditField
        ThrusterGeometryLabel         matlab.ui.control.Label
        hEditFieldLabel               matlab.ui.control.Label
        h                             matlab.ui.control.NumericEditField
        wEditFieldLabel               matlab.ui.control.Label
        w                             matlab.ui.control.NumericEditField
        lEditFieldLabel               matlab.ui.control.Label
        l                             matlab.ui.control.NumericEditField
        r_inLabel                     matlab.ui.control.Label
        r_in                          matlab.ui.control.NumericEditField
        r_outLabel                    matlab.ui.control.Label
        r_out                         matlab.ui.control.NumericEditField
        OthersLabel                   matlab.ui.control.Label
        t_pulseEditFieldLabel         matlab.ui.control.Label
        t_pulse                       matlab.ui.control.NumericEditField
        deltaEditFieldLabel           matlab.ui.control.Label
        delta                         matlab.ui.control.NumericEditField
        alphaEditFieldLabel           matlab.ui.control.Label
        alpha                         matlab.ui.control.NumericEditField
        m_bitEditFieldLabel           matlab.ui.control.Label
        m_bit                         matlab.ui.control.NumericEditField
        StatusEditFieldLabel          matlab.ui.control.Label
        Status                        matlab.ui.control.EditField
        n_tLabel                      matlab.ui.control.Label
        n_t                           matlab.ui.control.NumericEditField
        v_eLabel                      matlab.ui.control.Label
        exhaust_v                     matlab.ui.control.NumericEditField
        IspLabel                      matlab.ui.control.Label
        Isp                           matlab.ui.control.NumericEditField
        I_bitEditFieldLabel           matlab.ui.control.Label
        I_bit                         matlab.ui.control.NumericEditField
        OtimizationTab                matlab.ui.container.Tab
        ThrusterGeometry_2            matlab.ui.container.ButtonGroup
        ParallelPlateButton_2         matlab.ui.control.RadioButton
        CoaxialButton_2               matlab.ui.control.RadioButton
        ElectricParameterLabel_2      matlab.ui.control.Label
        LcEditFieldLabel_2            matlab.ui.control.Label
        Lc_min                        matlab.ui.control.NumericEditField
        LpeEditFieldLabel_2           matlab.ui.control.Label
        Lpe_min                       matlab.ui.control.NumericEditField
        LeEditFieldLabel_2            matlab.ui.control.Label
        Le_min                        matlab.ui.control.NumericEditField
        RcEditFieldLabel_2            matlab.ui.control.Label
        Rc_min                        matlab.ui.control.NumericEditField
        ReEditFieldLabel_2            matlab.ui.control.Label
        Re_min                        matlab.ui.control.NumericEditField
        RpeEditFieldLabel_2           matlab.ui.control.Label
        Rpe_min                       matlab.ui.control.NumericEditField
        RpEditFieldLabel_2            matlab.ui.control.Label
        Rp_min                        matlab.ui.control.NumericEditField
        CEditFieldLabel_2             matlab.ui.control.Label
        C_min                         matlab.ui.control.NumericEditField
        V0EditFieldLabel_2            matlab.ui.control.Label
        V0_min                        matlab.ui.control.NumericEditField
        PlasmaPropertiesLabel_2       matlab.ui.control.Label
        TeEditFieldLabel_2            matlab.ui.control.Label
        Te_min                        matlab.ui.control.NumericEditField
        n_eEditFieldLabel_2           matlab.ui.control.Label
        n_e_min                       matlab.ui.control.NumericEditField
        muEditFieldLabel_2            matlab.ui.control.Label
        mu_min                        matlab.ui.control.NumericEditField
        cbLc                          matlab.ui.control.CheckBox
        cbLe                          matlab.ui.control.CheckBox
        cbRe                          matlab.ui.control.CheckBox
        cbRp                          matlab.ui.control.CheckBox
        cbV0                          matlab.ui.control.CheckBox
        cbLpe                         matlab.ui.control.CheckBox
        cbRc                          matlab.ui.control.CheckBox
        cbRpe                         matlab.ui.control.CheckBox
        cbC                           matlab.ui.control.CheckBox
        ThrusterGeometryLabel_2       matlab.ui.control.Label
        hEditFieldLabel_2             matlab.ui.control.Label
        h_min                         matlab.ui.control.NumericEditField
        wEditFieldLabel_2             matlab.ui.control.Label
        w_min                         matlab.ui.control.NumericEditField
        lEditFieldLabel_2             matlab.ui.control.Label
        l_min                         matlab.ui.control.NumericEditField
        r_inLabel_2                   matlab.ui.control.Label
        r_in_min                      matlab.ui.control.NumericEditField
        r_outLabel_2                  matlab.ui.control.Label
        r_out_min                     matlab.ui.control.NumericEditField
        cbh                           matlab.ui.control.CheckBox
        cbw                           matlab.ui.control.CheckBox
        cbl                           matlab.ui.control.CheckBox
        cbr_in                        matlab.ui.control.CheckBox
        cbr_out                       matlab.ui.control.CheckBox
        OptionsLabel                  matlab.ui.control.Label
        RangeEditFieldLabel           matlab.ui.control.Label
        range                         matlab.ui.control.NumericEditField
        NPointsEditFieldLabel         matlab.ui.control.Label
        npoints                       matlab.ui.control.NumericEditField
        t_finalEditFieldLabel_2       matlab.ui.control.Label
        t_final_min                   matlab.ui.control.NumericEditField
        RunButton                     matlab.ui.control.Button
        Lc_max                        matlab.ui.control.NumericEditField
        Lpe_max                       matlab.ui.control.NumericEditField
        Le_max                        matlab.ui.control.NumericEditField
        Rc_max                        matlab.ui.control.NumericEditField
        Re_max                        matlab.ui.control.NumericEditField
        Rpe_max                       matlab.ui.control.NumericEditField
        Rp_max                        matlab.ui.control.NumericEditField
        C_max                         matlab.ui.control.NumericEditField
        V0_max                        matlab.ui.control.NumericEditField
        h_max                         matlab.ui.control.NumericEditField
        w_max                         matlab.ui.control.NumericEditField
        l_max                         matlab.ui.control.NumericEditField
        r_in_max                      matlab.ui.control.NumericEditField
        r_out_max                     matlab.ui.control.NumericEditField
        t_final_max                   matlab.ui.control.NumericEditField
        cbt_final                     matlab.ui.control.CheckBox
        MinLabel                      matlab.ui.control.Label
        MaxLabel                      matlab.ui.control.Label
        DebugLabel                    matlab.ui.control.Label
        debugArea                     matlab.ui.control.TextArea
        m_bitEditFieldLabel_2         matlab.ui.control.Label
        m_bit_min                     matlab.ui.control.NumericEditField
        m_bit_max                     matlab.ui.control.NumericEditField
        cbm_bit                       matlab.ui.control.CheckBox
        OtimizationObjectiveListBoxLabel  matlab.ui.control.Label
        OtimizationObjectiveListBox   matlab.ui.control.ListBox
        NumberofGenerationsEditFieldLabel  matlab.ui.control.Label
        numgen                        matlab.ui.control.NumericEditField
        PopulationSizeEditFieldLabel  matlab.ui.control.Label
        popsize                       matlab.ui.control.NumericEditField
        MutationScaleEditFieldLabel   matlab.ui.control.Label
        mutscale                      matlab.ui.control.NumericEditField
        CrossoverRateEditFieldLabel   matlab.ui.control.Label
        crossrate                     matlab.ui.control.NumericEditField
        DEParametersLabel             matlab.ui.control.Label
        Ablationm_bitTab              matlab.ui.container.Tab
        AblationModelDropDownLabel    matlab.ui.control.Label
        AblationModelDropDown         matlab.ui.control.DropDown
        AmbientPressure               matlab.ui.control.NumericEditField
        PropellantDropDownLabel       matlab.ui.control.Label
        PropellantDropDown            matlab.ui.control.DropDown
        SurfaceTemperatureKEditFieldLabel  matlab.ui.control.Label
        SurfaceTemperature            matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        Estimatedm_bitkgLabel         matlab.ui.control.Label
        estimated_m_bit               matlab.ui.control.NumericEditField
        SurfaceAream2Label            matlab.ui.control.Label
        SurfaceArea                   matlab.ui.control.NumericEditField
        PulseTimesEditFieldLabel      matlab.ui.control.Label
        PulseTime                     matlab.ui.control.NumericEditField
        AmbientPressureLabel          matlab.ui.control.Label
        SpecifyProperty               matlab.ui.container.ButtonGroup
        AmbientPressureButton         matlab.ui.control.ToggleButton
        ElectronDensityButton         matlab.ui.control.ToggleButton
    end

    
    methods (Access = private)
        
        function fit_value = fit(app,setup,cromo)
%             Lc,Le,Re,Rp,V0,Lpe,Rc,Rpe,C,geometry,h,w,l,Te,n_e,t_final,m_bit;
            Lc = cromo(1);Le = cromo(2);Re = cromo(3);Rp = cromo(4);V0 = cromo(5);
            Lpe = cromo(6);Rc = cromo(7);Rpe = cromo(8);C = cromo(9);geometry = cromo(10);
            h = cromo(11);w = cromo(12);l = cromo(13);Te = cromo(14);n_e = cromo(15);
            t_final = cromo(16);m_bit = cromo(17);
            
            tspan = [0 t_final];
            opts = odeset('RelTol','1e-6','AbsTol','1e-6','MaxStep', '1e-6');
            mu = 0.0000012566;
            delta = 0;
            R = 8.314;
            V_critic = 13300;
            gamma = 1;
            Req = Re + Rp + Rc + Rpe;
            
            y0 = [0,0,0,0,m_bit];
            %geometry = 0 (parallel)
            %geometry = 1 (coaxial)
            if ~geometry
                [t,y] = ode45(@(t,y) ppt_parallel(t,y,V0,C,Rc,Re,Rpe,Rp,mu,h,w,m_bit,Lc,Le,Lpe,delta,Te,n_e,gamma,R,V_critic), tspan, y0, opts);
                Ekinetic = y(:,5).*y(:,3).*y(:,3)/2;
                m_t = [0; diff(y(:,5))];
                    
                C_m = gamma*(2/(gamma+1))^(gamma+1/(2*(gamma-1)));
                C_f = gamma*(2/(gamma+1))^(gamma/(gamma-1)) + (1+(gamma-1)/2)^(-gamma/(gamma-1));
                
                Eb = (0.5)*(Lc+Le + mu*h.*y(:,1)/w).*y(:,4).^2;
                I_bit = zeros(length(t),1);
                for i = 2:length(t)
                    int = trapz(t(1:i),mu*h.*y(1:i,4).*y(1:i,4)./(2.*w) + m_t(1:i).*sqrt(gamma.*R.*Te).*(C_f/C_m));
                    I_bit(i) = int;
                end
                
                I = y(:,4);
                V = V0 - y(:,2)/C;
                Ec = C*V.^2/2;
                Eohm = zeros(length(t),1);  
                for i = 2:length(t)
                    int = trapz(t(1:i),Req*y(1:i,4).*y(1:i,4));
                    Eohm(i) = int;
                end
                
                try
                    index = find(abs(y(:,1)-l) < 0.001,1);
                    t_prime = t(index);
                    exhaust_v = y(index,3);
                    %app.Isp.Value = app.exhaust_v.Value/app.g.Value;
                    %app.I_bit.Value = I_bit(end);
                    n_t= 100*m_bit.*exhaust_v.*exhaust_v./(2*Ec(1));
                    fit_value = n_t;
                catch
                    fit_value = 0;
                end
            else
                r_in = h;
                r_out = w;
                [t,y] = ode45(@(t,y) ppt_coaxial(t,y,V0,C,Rc,Re,Rpe,Rp,mu,m_bit,Lc,Le,Lpe,delta,Te,n_e,r_out,r_in,gamma,R,V_critic), tspan, y0, opts);
                Ekinetic = y(:,5).*y(:,3).*y(:,3)/2;
                Eb = (0.5)*(Lc+Le+mu*log(r_out/r_in)*y0(1)/(4*pi)).*y(:,4).^2;
                I_bit = zeros(length(t),1);
                for i = 2:length(t)
                    int = trapz(t(1:i),mu*log(r_out./r_in).*y(1:i,4).*y(1:i,4)./(4.*pi));
                    I_bit(i) = int;
                end
                I = y(:,4);
                V = V0 - y(:,2)/C;
                Ec = C*V.^2/2;
                Eohm = zeros(length(t),1);

                for i = 2:length(t)
                    int = trapz(t(1:i),Req*y(1:i,4).*y(1:i,4));
                    Eohm(i) = int;
                end
                
                try
                    index = find(abs(y(:,1)-app.l.Value) < 0.001,1);
                    t_prime = t(index);
                    exhaust_v = y(index,3);
                    Isp = exhaust_v/g;
                    I_bit = I_bit(end);
                    n_t = 100*m_bit.*exhaust_v.*exhaust_v./(2*Ec(1));
                    fit_value = n_t;
                catch
                    fit_value = 0;
                end
           
            end            
                
            function y = ppt_parallel(t,y0,V0,C,Rc,Re,Rpe,Rplasma,mu,h,w,m_bit,Lc,Le,Lpe,delta,Te,n_e,gamma,R,V_critic)
                %Lpe = mu*h*y0(1)/w +mu*delta*h/(2*w);
                L = Lpe + Lc + Le;
                %Rplasma = 8.08*h*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/(w*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
                
                m_dot = y0(4)*y0(4)*mu*h/(4.404*w*V_critic);
                C_m = gamma*(2/(gamma+1))^((gamma+1)/(2*(gamma-1)));
                C_f = gamma*(2/(gamma+1))^(gamma/(gamma-1)) + (1+(gamma-1)/2)^(-gamma/(gamma-1));
                
                y = zeros(5,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = ((mu*h*y0(4)*y0(4)/(2*w) - m_dot*y0(1) + m_dot*sqrt(gamma*R*Te)*(C_f/C_m)))/y0(5);
                y(4) = (-y0(2)/C - mu*h*y0(3)*y0(4)/w - Req*y0(4) + V0)/(L);
                y(5) = m_dot;
            end
        
            function y = ppt_coaxial(t,y0,V0,C,Rc,Re,Rpe,Rplasma,mu,m_bit,Lc,Le,Lpe,delta,Te,n_e,r_out,r_in,gamma, R,V_critic)
                %Lpe = mu*log(r_out/r_in)*y0(1)/(4*pi) + mu*delta*log(r_out/r_in)/(4*pi);
                L = Lpe + Lc + Le;
                
                %Rplasma = 2.57*(r_out-r_in)*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/((r_out+r_in)*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
                
                m_dot = y0(4)*y0(4)*mu*log(r_out/r_in)/(4.404*V_critic);
                C_m = gamma*(2/(gamma+1))^(gamma+1/(2*(gamma-1)));
                C_f = gamma*(2/(gamma+1))^(gamma/(gamma-1)) + (1+(gamma-1)/2)^(-gamma/(gamma-1));
                
                y = zeros(5,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = (mu*log(r_out/r_in)*y0(4)*y0(4) - m_dot*y0(1) + m_dot*sqrt(gamma*R*Te)*(C_f/C_m))/(4*y0(5)*pi);
                y(4) = (-y0(2)/C - mu*log(r_out/r_in)*y0(3)*y0(4)/(4*pi) - Req*y0(4) + V0)/(L);
                y(5) = m_dot;
            end
        end
        
        
        function [min, max, idx] = var_or_fixed(app)
            min = [];
            max = [];
            idx = zeros(1,14);
%             app.debugArea.Value =sprintf("inicio");
            if app.cbLc.Value == 0
                min = [min app.Lc_min.Value];
                max = [max app.Lc_max.Value];
            else
                min = [min app.Lc_min.Value];
                max = [max app.Lc_min.Value];
                idx(1) = 1;
            end
            
            
            if app.cbLe.Value == 0
                min = [min app.Le_min.Value];
                max = [max app.Le_max.Value];
            else
                min = [min app.Le_min.Value];
                max = [max app.Le_min.Value];
                idx(2) = 2;
            end
            
            if app.cbRe.Value == 0
                min = [min app.Re_min.Value];
                max = [max app.Re_max.Value];
            else
                min = [min app.Re_min.Value];
                max = [max app.Re_min.Value];
                idx(3) = 3;
            end
            
            if app.cbRp.Value == 0
                min = [min app.Rp_min.Value];
                max = [max app.Rp_max.Value];
            else
                min = [min app.Lc_min.Value];
                max = [max app.Lc_min.Value];
                idx(4) = 4;
            end
            
            if app.cbV0.Value == 0
                min = [min app.V0_min.Value];
                max = [max app.V0_max.Value];
            else
                min = [min app.V0_min.Value];
                max = [max app.V0_min.Value];
                idx(5) = 5;
            end
            
            if app.cbLpe.Value == 0
                min = [min app.Lpe_min.Value];
                max = [max app.Lpe_max.Value];
            else
                min = [min app.Lpe_min.Value];
                max = [max app.Lpe_min.Value];
                idx(6) = 6;
            end
            
            if app.cbRc.Value == 0
                min = [min app.Rc_min.Value];
                max = [max app.Rc_max.Value];
            else
                min = [min app.Rc_min.Value];
                max = [max app.Rc_min.Value];
                idx(7) = 7;
            end
            
            if app.cbRpe.Value == 0
                min = [min app.Rpe_min.Value];
                max = [max app.Rpe_max.Value];
            else
                min = [min app.Rpe_min.Value];
                max = [max app.Rpe_min.Value];
                idx(8) = 8;
            end
            
            if app.cbC.Value == 0
                min = [min app.C_min.Value];
                max = [max app.C_max.Value];
            else
                min = [min app.C_min.Value];
                max = [max app.C_min.Value];
                idx(9) = 9;
            end
            
            if app.ParallelPlateButton_2.Value
            
                if app.cbh.Value == 0
                    min = [min app.h_min.Value];
                    max = [max app.h_max.Value];
                else
                    min = [min app.h_min.Value];
                    max = [max app.h_min.Value];
                    idx(10) = 10;
                end
                
                if app.cbw.Value == 0
                    min = [min app.w_min.Value];
                    max = [max app.w_max.Value];
                else
                    min = [min app.w_min.Value];
                    max = [max app.w_min.Value];
                    idx(11) = 11;
                end
            else
                if app.cbr_in.Value == 0
                    min = [min app.r_in_min.Value];
                    max = [max app.r_in_max.Value];
                else
                    min = [min app.r_in_min.Value];
                    max = [max app.r_in_min.Value];
                    idx(10) = 10;
                end
            
                if app.cbr_out.Value == 0
                    min = [min app.r_out_min.Value];
                    max = [max app.r_out_max.Value];
                else
                    min = [min app.r_out_min.Value];
                    max = [max app.r_out_min.Value];
                    idx(11) = 11;
                end
            end
            
            if app.cbl.Value == 0
                min = [min app.l_min.Value];
                max = [max app.l_max.Value];
            else
                min = [min app.l_min.Value];
                max = [max app.l_min.Value];
                idx(12) = 12;
            end
            
            
            if app.cbt_final.Value == 0
                min = [min app.t_final_min.Value];
                max = [max app.t_final_max.Value];
            else
                min = [min app.t_final_min.Value];
                max = [max app.t_final_min.Value];
                idx(13) = 13;
            end
            
            if app.cbm_bit.Value == 0
                min = [min app.m_bit_min.Value];
                max = [max app.m_bit_max.Value];
            else
                min = [min app.m_bit_min.Value];
                max = [max app.m_bit_min.Value];
                idx(14) = 14;
            end
            idx = find(idx); %indices diferentes de 0
        end
        
        function a = plot_result(app, setup, result)
            v = setup.MinValues  + (setup.MaxValues - setup.MinValues).*abs(cos(result.eliteCromo));
            v(setup.idx) = setup.MinValues(setup.idx);
            
            Lc = v(1); m1 = sprintf('\nLc = %d H\n', Lc);
            Le = v(2); m2 = sprintf('Le = %d H\n', Le);
            Re = v(3); m3 = sprintf('Re = %d ohms\n', Re);
            Rp = v(4); m4 = sprintf('Rp = %d ohms\n', Rp);
            V0 = v(5); m5 = sprintf('V0 = %d V\n', V0);
            Lpe = v(6); m6 = sprintf('Lpe = %d H\n', Lpe);
            Rc = v(7); m7 = sprintf('Rc = %d ohms\n', Rc);
            Rpe = v(8); m8 = sprintf('Rpe = %d ohms\n', Rpe);
            C = v(9); m9 = sprintf('C = %d F\n', C);
            h = v(10); m10 = sprintf('h = %d m\n', h);
            w = v(11); m11 = sprintf('w = %d m\n', w);
            l = v(12); m12 = sprintf('l = %d m\n', l);
            t_final = v(13); m13 = sprintf('t_final = %d s\n', t_final);
            m_bit = v(14); m14 = sprintf('m_bit = %d kg\n', m_bit);
            
            if setup.otim_obj == 1
                text = sprintf('\nMax. Thruster Efficiency = %.4f %% \n', result.eliteValue);    
            elseif setup.otim_obj == 2
                text = sprintf('\nMax. Specific Impulse = %d s\n', result.eliteValue);          
            else
                text = sprintf('\nMax. Thrust/Power = %d \n', result.eliteValue);         
            end
            
            text = strcat(sprintf('Resultados: \n ---------------------------\n'),{m1}, {m2}, {m3}, {m4}, {m5}, {m6}, {m7}, {m8}, {m9}, {m10}, {m11}, {m12}, {m13}, {m14}, sprintf('---------------------------\n'), {text});
            app.debugArea.Value = text;
            a = 0;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            %app.NumPPTGUIFigure.WindowState = 'maximized';            
        end

        % Value changed function: SolveButton
        function SolveButtonValueChanged(app, event)
            %limpa os resultados
%             app.ClearButtonPushed(@ClearButtonPushed);
%             ClearButtonPushed(app, matlab.ui.eventdata.ClearButtonPushed)
            run_sucess = 1;
            tspan = [0 app.t_final.Value];
            app.Status.Value = "Iniciando";
%             y0 = [0,0,0,0,app.m_bit.Value];
%             y0 = [0,0,0,0];
            opts = odeset('RelTol',app.RelTol.Value,'AbsTol',app.AbsTol.Value,'MaxStep', app.MaxStep.Value);
%             app.Rp.Value = 8.08*app.h.Value*sqrt(app.mu.Value*log(1.24e7*(app.Te.Value^3/app.n_e.Value)^0.5)/app.t_pulse.Value)/(app.w.Value*app.Te.Value^(0.75));
%             Rplasma = 8.08*app.h.Value*sqrt(app.mu.Value*log(1.24e7*((app.Te.Value*11604.5250061)^3/app.n_e.Value)^0.5)/app.t_pulse.Value)/(app.w.Value*(app.Te.Value*11604.5250061)^(0.75))
%             Req = app.Rc.Value + app.Re.Value + app.Rpe.Value + Rplasma;
            
            
            if app.ParallelPlateButton.Value
                if app.SlugButton.Value
                    y0 = [0,0,0,0];
                    switch app.NumericalMethod.Value
                        case 'ode23'
                            [t,y] = ode23(@(t,y) ppt_parallel(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value), tspan, y0, opts);
                        case 'ode45'
                            [t,y] = ode45(@(t,y) ppt_parallel(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value), tspan, y0, opts);
                        case 'ode113'
                            [t,y] = ode113(@(t,y) ppt_parallel(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value), tspan, y0, opts);
                    end
                    Ekinetic = app.m_bit.Value*y(:,3).*y(:,3)/2;
                    Eb = (0.5)*(app.Lc.Value+app.Le.Value + app.mu.Value*app.h.Value.*y(:,1)/app.w.Value).*y(:,4).^2;
                    I_bit = zeros(length(t),1);
                    for i = 2:length(t)
                        int = trapz(t(1:i),app.mu.Value*app.h.Value.*y(1:i,4).*y(1:i,4)./(2.*app.w.Value));
                        I_bit(i) = int;
                    end
                else
                    y0 = [0,0,0,0,app.m_bit.Value];
                    switch app.NumericalMethod.Value
                        case 'ode23'
                            [t,y] = ode23(@(t,y) ppt_parallel_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                        case 'ode45'
                            [t,y] = ode45(@(t,y) ppt_parallel_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                        case 'ode113'
                            [t,y] = ode113(@(t,y) ppt_parallel_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                    end
                    Ekinetic = y(:,5).*y(:,3).*y(:,3)/2;
%                     plot(t, [0;diff(y(:,5))])
                    %plot(t,y(:,5))
                    %ylabel('Mass flow rate')
                    m_t = [0; diff(y(:,5))];
                    
                    C_m = app.gamma.Value*(2/(app.gamma.Value+1))^(app.gamma.Value+1/(2*(app.gamma.Value-1)));
                    C_f = app.gamma.Value*(2/(app.gamma.Value+1))^(app.gamma.Value/(app.gamma.Value-1)) + (1+(app.gamma.Value-1)/2)^(-app.gamma.Value/(app.gamma.Value-1));
                
                    Eb = (0.5)*(app.Lc.Value+app.Le.Value + app.mu.Value*app.h.Value.*y(:,1)/app.w.Value).*y(:,4).^2;
                    I_bit = zeros(length(t),1);
                    for i = 2:length(t)
                        int = trapz(t(1:i),app.mu.Value*app.h.Value.*y(1:i,4).*y(1:i,4)./(2.*app.w.Value) + m_t(1:i).*sqrt(app.gamma.Value.*app.R.Value.*app.Te.Value).*(C_f/C_m));
                        I_bit(i) = int;
                    end
                end
                
            else
                if app.SlugButton.Value
                    y0 = [0,0,0,0];
                    switch app.NumericalMethod.Value
                        case 'ode23'
                            [t,y] = ode23(@(t,y) ppt_coaxial(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value), tspan, y0, opts);
                        case 'ode45'
                            [t,y] = ode45(@(t,y) ppt_coaxial(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value), tspan, y0, opts);
                        case 'ode113'
                            [t,y] = ode113(@(t,y) ppt_coaxial(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value), tspan, y0, opts);
                    end
                    Ekinetic = app.m_bit.Value*y(:,3).*y(:,3)/2;
                    
                else
                    y0 = [0,0,0,0,app.m_bit.Value];
                    switch app.NumericalMethod.Value
                        case 'ode23'
                            [t,y] = ode23(@(t,y) ppt_coaxial_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                        case 'ode45'
                            [t,y] = ode45(@(t,y) ppt_coaxial_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                        case 'ode113'
                            [t,y] = ode113(@(t,y) ppt_coaxial_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                    end
                    Ekinetic = y(:,5).*y(:,3).*y(:,3)/2;
                    %plot(t, [0;diff(y(:,5))])
                    %ylabel('Mass flow rate')
                    
                end
                Eb = (0.5)*(app.Lc.Value+app.Le.Value+app.mu.Value*log(app.r_out.Value/app.r_in.Value)*y0(1)/(4*pi)).*y(:,4).^2;
                I_bit = zeros(length(t),1);
                for i = 2:length(t)
                    int = trapz(t(1:i),app.mu.Value.*log(app.r_out.Value./app.r_in.Value).*y(1:i,4).*y(1:i,4)./(4.*pi));
                    I_bit(i) = int;
                end
            end
            
            I = y(:,4);
            V = app.V0.Value - y(:,2)/app.C.Value;
            Ec = app.C.Value*V.^2/2;
            Eohm = zeros(length(t),1);
            
            
            for i = 2:length(t)
                int = trapz(t(1:i),Req*y(1:i,4).*y(1:i,4));
                Eohm(i) = int;
            end
            
            
            %plot 1
            yyaxis(app.current_plot,'left')
            plot(app.current_plot,t,V)
            yyaxis(app.current_plot,'right')
            plot(app.current_plot,t,I)
            ylabel(app.current_plot,'Current (A)')
            xlim(app.current_plot,[0,app.t_final.Value])
            
            %plot 2
            plot(app.energy_plot,t,Eohm) 
            hold(app.energy_plot,'on')
            plot(app.energy_plot,t,Ec)
            plot(app.energy_plot,t,Ekinetic)
            plot(app.energy_plot,t,Eb)
            plot(app.energy_plot,t,Eohm+Ec+Ekinetic+Eb)
            legend(app.energy_plot,'Ohmic','Capacitor','Kinetic','Indutance Field','Total')
            xlim(app.energy_plot,[0,app.t_final.Value])
            hold(app.energy_plot,'off')
            
            %exhaust velocity, Isp
%             I_bit = zeros(length(t),1);
%             for i = 2:length(t)
%                 int = trapz(t(1:i),app.mu.Value*app.h.Value.*y(1:i,4).*y(1:i,4)./(2.*app.w.Value));
%                 I_bit(i) = int;
%             end
             try
                index = find(abs(y(:,1)-app.l.Value) < 0.001,1)
                t_prime = t(index)
                app.exhaust_v.Value = y(index,3);
                app.Isp.Value = app.exhaust_v.Value/app.g.Value;
                app.I_bit.Value = I_bit(end);

                
             catch
                run_sucess = 0;
                %disp('Teste');
                app.Status.Value = "Tempo insuficiente";
                app.t_final.Value = 1.25*app.t_final.Value;
                SolveButtonValueChanged(app,[]);
                yyaxis(app.vel_pos, 'right')
                plot(app.vel_pos,t,y(:,1))
%               hold(app.vel_pos, 'on')
                ylabel(app.vel_pos, 'Position (m)')
                yyaxis(app.vel_pos, 'left')
                plot(app.vel_pos,t,y(:,3))
                xlim(app.vel_pos,[0,app.t_final.Value])
             end
            
            %thurst efficiency considering slug model
            app.n_t.Value = 100*app.m_bit.Value.*app.exhaust_v.Value.*app.exhaust_v.Value./(2*Ec(1));
            %plot 3
            yyaxis(app.vel_pos, 'right')
            plot(app.vel_pos,t,y(:,1))
%             hold(app.vel_pos, 'on')
            ylabel(app.vel_pos, 'Position (m)')
            yyaxis(app.vel_pos, 'left')
            plot(app.vel_pos,t,y(:,3))
            xlim(app.vel_pos,[0,app.t_final.Value])
%             hold(app.vel_pos, 'off')
            if run_sucess
                 app.Status.Value = "Success";
                 voltage = V;
                 time = t;
                 i_bit = app.I_bit.Value;
                 isp = app.Isp.Value;
                 v_e = app.exhaust_v.Value;
                 n_t = app.n_t.Value;
                 x_s = y(:,1);
                 xdot_s = y(:,3);
                 if app.ParallelPlateButton.Value
                    if app.SlugButton.Value
                        if isfile('ppt_parallel_slug.mat')
                            save('ppt_parallel_slug1.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        else
                            save('ppt_parallel_slug.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        end
                    else
                        if isfile('ppt_parallel_modslug.mat')
                            save('ppt_parallel_modslug1.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        else
                            save('ppt_parallel_modslug.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        end
                    end
                 else
                     if app.SlugButton.Value
                        if isfile('ppt_coax_slug.mat')
                            save('ppt_coax_slug1.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        else
                            save('ppt_coax_slug.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        end
                    else
                        if isfile('ppt_coax_modslug.mat')
                            save('ppt_coax_modslug1.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        else
                            save('ppt_coax_modslug.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        end
                    end
                 end
            end
            
            function y = ppt_parallel(t,y0,V0,C,Rc,Re,Rpe,mu,h,w,m_bit,Lc,Le,delta,Te,n_e,t_pulse)
                Lpe = mu*h*y0(1)/w +mu*delta*h/(2*w);
                L = Lpe + Lc + Le;
                Rplasma = 8.08*h*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/(w*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
    
                y = zeros(4,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = mu*h*y0(4)*y0(4)/(2*m_bit*w);
                y(4) = (-y0(2)/C - mu*h*y0(3)*y0(4)/w - Req*y0(4) + V0)/(L);

            end
        
            function y = ppt_parallel_modslug(t,y0,V0,C,Rc,Re,Rpe,mu,h,w,m_bit,Lc,Le,delta,Te,n_e,t_pulse,gamma,R,V_critic)
                Lpe = mu*h*y0(1)/w +mu*delta*h/(2*w);
                L = Lpe + Lc + Le;
                Rplasma = 8.08*h*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/(w*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
                
                m_dot = y0(4)*y0(4)*mu*h/(4.404*w*V_critic);
                C_m = gamma*(2/(gamma+1))^((gamma+1)/(2*(gamma-1)));
                C_f = gamma*(2/(gamma+1))^(gamma/(gamma-1)) + (1+(gamma-1)/2)^(-gamma/(gamma-1));
                
                y = zeros(5,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = ((mu*h*y0(4)*y0(4)/(2*w) - m_dot*y0(1) + m_dot*sqrt(gamma*R*Te)*(C_f/C_m)))/y0(5);
                y(4) = (-y0(2)/C - mu*h*y0(3)*y0(4)/w - Req*y0(4) + V0)/(L);
                y(5) = m_dot;

            end
        
            function y = ppt_coaxial(t,y0,V0,C,Rc,Re,Rpe,mu,m_bit,Lc,Le,delta,Te,n_e,t_pulse,r_out,r_in)
                Lpe = mu*log(r_out/r_in)*y0(1)/(4*pi) + mu*delta*log(r_out/r_in)/(4*pi);
                L = Lpe + Lc + Le;
                
                Rplasma = 2.57*(r_out-r_in)*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/((r_out+r_in)*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
                
                y = zeros(4,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = mu*log(r_out/r_in)*y0(4)*y0(4)/(4*m_bit*pi);
                y(4) = (-y0(2)/C - mu*log(r_out/r_in)*y0(3)*y0(4)/(4*pi) - Req*y0(4) + V0)/(L);

            end
        
            function y = ppt_coaxial_modslug(t,y0,V0,C,Rc,Re,Rpe,mu,m_bit,Lc,Le,delta,Te,n_e,t_pulse,r_out,r_in,gamma, R,V_critic)
                Lpe = mu*log(r_out/r_in)*y0(1)/(4*pi) + mu*delta*log(r_out/r_in)/(4*pi);
                L = Lpe + Lc + Le;
                
                Rplasma = 2.57*(r_out-r_in)*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/((r_out+r_in)*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
                
                m_dot = y0(4)*y0(4)*mu*log(r_out/r_in)/(4.404*V_critic);
                C_m = gamma*(2/(gamma+1))^(gamma+1/(2*(gamma-1)));
                C_f = gamma*(2/(gamma+1))^(gamma/(gamma-1)) + (1+(gamma-1)/2)^(-gamma/(gamma-1));
                
                y = zeros(5,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = (mu*log(r_out/r_in)*y0(4)*y0(4) - m_dot*y0(1) + m_dot*sqrt(gamma*R*Te)*(C_f/C_m))/(4*y0(5)*pi);
                y(4) = (-y0(2)/C - mu*log(r_out/r_in)*y0(3)*y0(4)/(4*pi) - Req*y0(4) + V0)/(L);
                y(5) = m_dot;
            end
        end

        % Selection changed function: ThrusterGeometry
        function ThrusterGeometrySelectionChanged(app, event)
            selectedButton = app.ThrusterGeometry.SelectedObject;
            
            if app.CoaxialButton.Value
                app.r_in.Editable = 'on';
                app.r_out.Editable = 'on';
                app.h.Editable = 'off';
                app.w.Editable = 'off';
            else
                app.r_in.Editable = 'off';
                app.r_out.Editable = 'off';
                app.h.Editable = 'on';
                app.w.Editable = 'on';
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.I_bit.Value = 0;
            app.exhaust_v.Value = 0;
            app.Isp.Value = 0;
            app.Status.Value = " ";
            app.n_t.Value = 0;
        end

        % Value changed function: PPTExample
        function PPTExampleValueChanged(app, event)
            value = app.PPTExample.Value;
            switch value
                case "LES 6"
                    app.Lc.Value = 0;
                    app.Lpe.Value = 0;
                    app.Le.Value = 3.4e-8;
                    app.Rc.Value = 0.03;
                    app.Re.Value = 0;
                    app.Rpe.Value = 0;
                    app.Rp.Value = 0.074;
                    app.C.Value = 2e-6;
                    app.V0.Value = 1360;
                    app.h.Value = 0.03;
                    app.w.Value = 0.01;
                    app.l.Value = 0.006;
                    app.Te.Value = 1.5;
                    app.n_e.Value = 1e21;
                    app.t_pulse.Value = 4e-7;
                    app.delta.Value = 0;
                    app.alpha.Value = 1;
                    app.m_bit.Value = 1e-8;
                    app.mu.Value = 1.257e-6;
                    app.MaxStep.Value = 1e-5;
                    app.AbsTol.Value = 1e-4;
                    app.RelTol.Value = 0.0001;
                    app.t_final.Value = 2.5e-6;
                    app.g.Value = 9.81;
                    %app.r_in.Value = 0.025;
                    %app.r_out.Value = 0.03;
                    app.gamma.Value = 1;
                    app.R.Value = 8.314;
                    app.V_critic.Value = 13300;
                case "LES 8/9"
                    app.Lc.Value = 0;
                    app.Lpe.Value = 0;
                    app.Le.Value = 3.5e-8;
                    app.Rc.Value = 0.03;
                    app.Re.Value = 0;
                    app.Rpe.Value = 0;
                    app.Rp.Value = 0.074;
                    app.C.Value = 17e-6;
                    app.V0.Value = 1538;
                    app.h.Value = 0.0254;
                    app.w.Value = 0.0254;
                    app.l.Value = 0.0254;
                    app.Te.Value = 5;
                    app.n_e.Value = 1e21;
                    app.t_pulse.Value = 10e-7;
                    app.delta.Value = 0;
                    app.alpha.Value = 1;
                    app.m_bit.Value = 2.85e-8;
                    app.mu.Value = 1.257e-6;
                    app.MaxStep.Value = 1e-5;
                    app.AbsTol.Value = 1e-4;
                    app.RelTol.Value = 0.0001;
                    app.t_final.Value = 2.5e-6;
                    app.g.Value = 9.81;
                    %app.r_in.Value = 0.025;
                    %app.r_out.Value = 0.03;
                    app.gamma.Value = 1;
                    app.R.Value = 8.314;
                    app.V_critic.Value = 13300;
                case "BIT"
                    app.Lc.Value = 0;
                    app.Lpe.Value = 0;
                    app.Le.Value = 130e-9;
                    app.Rc.Value = 100e-3;
                    app.Re.Value = 0;
                    app.Rpe.Value = 0;
                    app.Rp.Value = 0.074;
                    app.C.Value = 2e-6;
                    app.V0.Value = 1500;
                    %app.h.Value = 0.0254;
                    %app.w.Value = 0.0254;
                    app.l.Value = 50e-3;
                    app.Te.Value = 5;
                    app.n_e.Value = 1e21;
                    app.t_pulse.Value = 10e-7;
                    app.delta.Value = 0;
                    app.alpha.Value = 1;
                    app.m_bit.Value = 2.73e-9;
                    app.mu.Value = 1.257e-6;
                    app.MaxStep.Value = 1e-5;
                    app.AbsTol.Value = 1e-4;
                    app.RelTol.Value = 0.0001;
                    app.t_final.Value = 4e-6;
                    app.g.Value = 9.81;
                    app.r_in.Value = 0.75e-3;
                    app.r_out.Value = 3e-3;
                    app.gamma.Value = 1;
                    app.R.Value = 8.314;
                    app.V_critic.Value = 13300;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            import DE;
            app.debugArea.Value = sprintf("Otimização iniciada!\n");
            drawnow();
            setup = DE.defaultSetup();
            if app.ParallelPlateButton_2.Value
                setup.ppt_geometry = 0;
            else
                setup.ppt_geometry = 1;
            end
            
            if app.OtimizationObjectiveListBox.Value == "Thrust/Power"
                setup.otim_obj = 3;
            elseif app.OtimizationObjectiveListBox.Value == "Specific Impulse"
                setup.otim_obj = 2;
            else
                setup.otim_obj = 1;
            end
    
            [setup.MinValues, setup.MaxValues, setup.idx] = var_or_fixed(app);
            setup.varCount = length(setup.MinValues);
            setup.crossoverRate = app.crossrate.Value;
            setup.mutationScale = app.mutscale.Value;
            setup.populationAmount = app.popsize.Value;
            setup.rounds = app.numgen.Value;
            simu = DE(setup);
            result = simu.run()
            assignin('base','result',result);
            assignin('base','setup',setup);
            
            %end_text = sprintf("\nOtimização finalizada!\n -------------------------------------\n Resultados encontrados: \n");
            %app.debugArea.Value = strcat(app.debugArea.Value, end_text);
            %drawnow();
            
            plot_result(app,setup, result);


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            k = 1.380648*10^-23;
            m0 =1.66*10^-23;
            mptfe = m0*(12.011*2 + 4*19.00)/6;
            M = 100.0220/1000;
            NA = 6.02e23;
            Ts = app.SurfaceTemperature.Value;
            Pamb = app.AmbientPressure.Value;
            A = app.SurfaceArea.Value;
            
            if app.AmbientPressureButton.Value == 1
                Pc = 1.847*10^15*Pamb;
                Tc = 20815;
                n0 = Pc*exp(-Tc/Ts)/(k*Ts);
            else
                n0 = app.AmbientPressure.Value;
            end

            
            if app.AblationModelDropDown.Value == "Langmuir"
                if app.PropellantDropDown.Value == "PTFE"     
                    m = m0*(12.011*2 + 4*19.00)/6;
                    atomic_emission_rate = A*sqrt(8*k*Ts/(pi*m))*n0/4;
                    app.estimated_m_bit.Value = app.PulseTime.Value*atomic_emission_rate*m;
                elseif app.PropellantDropDown.Value == "PE"
                    m = m0*(12.011*2 + 4*1)/6;
                end
            elseif app.AblationModelDropDown.Value == "Knudsen Double Layer"
                if app.PropellantDropDown.Value == "PTFE"
                    m = m0*(12.011*2 + 4*19.00)/6;
                elseif app.PropellantDropDown.Value == "PE"
                    m = m0*(12.011*2 + 4*1)/6;
                end
            end
            
        end

        % Selection changed function: SpecifyProperty
        function SpecifyPropertySelectionChanged(app, event)
            selectedButton = app.SpecifyProperty.SelectedObject
            if selectedButton.Text == 'Ambient Pressure'
                app.AmbientPressureLabel.Text = "Ambient Pressure (Pa)";
                app.AmbientPressure.Value = 101000;
            else
                app.AmbientPressureLabel.Text = "Electron Density (m^3)";
                app.AmbientPressure.Value = 1e21;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NumPPTGUIFigure = uifigure;
            app.NumPPTGUIFigure.Name = 'NumPPTG';
            app.TabGroup = uitabgroup(app.NumPPTGUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.DynamicalModel = uibuttongroup(app.GeneralTab);
            app.DynamicalModel.Title = 'Dynamical Model';
            app.SlugButton = uiradiobutton(app.DynamicalModel);
            app.SlugButton.Text = 'Slug';
            app.SlugButton.Value = true;
            app.ModSlugButton = uiradiobutton(app.DynamicalModel);
            app.ModSlugButton.Text = 'Mod. Slug';
            app.ThrusterGeometry = uibuttongroup(app.GeneralTab);
            app.ThrusterGeometry.SelectionChangedFcn = createCallbackFcn(app, @ThrusterGeometrySelectionChanged, true);
            app.ThrusterGeometry.Title = 'Thruster Geometry';
            app.ParallelPlateButton = uiradiobutton(app.ThrusterGeometry);
            app.ParallelPlateButton.Text = 'Parallel Plate';
            app.ParallelPlateButton.Value = true;
            app.CoaxialButton = uiradiobutton(app.ThrusterGeometry);
            app.CoaxialButton.Text = 'Coaxial';
            app.ClearButton = uibutton(app.GeneralTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SolveButton = uibutton(app.GeneralTab, 'state');
            app.SolveButton.ValueChangedFcn = createCallbackFcn(app, @SolveButtonValueChanged, true);
            app.SolveButton.Text = 'Solve';
            app.ResultsLabel = uilabel(app.GeneralTab);
            app.ResultsLabel.Text = 'Results';
            app.energy_plot = uiaxes(app.GeneralTab);
            title(app.energy_plot, '')
            xlabel(app.energy_plot, 'Time')
            ylabel(app.energy_plot, 'Energy (J)')
            app.current_plot = uiaxes(app.GeneralTab);
            title(app.current_plot, '')
            xlabel(app.current_plot, 'Time')
            ylabel(app.current_plot, 'Voltage (V)')
            app.vel_pos = uiaxes(app.GeneralTab);
            title(app.vel_pos, '')
            xlabel(app.vel_pos, 'Time')
            ylabel(app.vel_pos, {'Velocity (m/s)'; ''})
            app.PreDefinedConfigLabel = uilabel(app.GeneralTab);
            app.PreDefinedConfigLabel.Text = 'Pre-Defined Config.';
            app.PPTExampleDropDownLabel = uilabel(app.GeneralTab);
            app.PPTExampleDropDownLabel.Text = 'PPT Example';
            app.PPTExample = uidropdown(app.GeneralTab);
            app.PPTExample.Items = {'LES 6', 'LES 8/9', 'BIT', 'Option 4'};
            app.PPTExample.ValueChangedFcn = createCallbackFcn(app, @PPTExampleValueChanged, true);
            app.PPTExample.Value = 'LES 6';
            app.ConstantValuesLabel = uilabel(app.GeneralTab);
            app.ConstantValuesLabel.Text = 'Constant Values';
            app.gLabel = uilabel(app.GeneralTab);
            app.gLabel.Text = 'g';
            app.g = uieditfield(app.GeneralTab, 'numeric');
            app.g.Value = 9.81;
            app.gammaEditFieldLabel = uilabel(app.GeneralTab);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gamma = uieditfield(app.GeneralTab, 'numeric');
            app.gamma.Value = 1;
            app.REditFieldLabel = uilabel(app.GeneralTab);
            app.REditFieldLabel.Text = 'R';
            app.R = uieditfield(app.GeneralTab, 'numeric');
            app.R.Value = 8.314;
            app.V_criticEditFieldLabel = uilabel(app.GeneralTab);
            app.V_criticEditFieldLabel.Text = 'V_critic';
            app.V_critic = uieditfield(app.GeneralTab, 'numeric');
            app.V_critic.Value = 13300;
            app.PlasmaPropertiesLabel = uilabel(app.GeneralTab);
            app.PlasmaPropertiesLabel.Text = 'Plasma Properties';
            app.TeEditFieldLabel = uilabel(app.GeneralTab);
            app.TeEditFieldLabel.Text = 'Te';
            app.Te = uieditfield(app.GeneralTab, 'numeric');
            app.Te.Value = 1.5;
            app.n_eEditFieldLabel = uilabel(app.GeneralTab);
            app.n_eEditFieldLabel.Text = 'n_e';
            app.n_e = uieditfield(app.GeneralTab, 'numeric');
            app.n_e.Value = 1e+21;
            app.muEditFieldLabel = uilabel(app.GeneralTab);
            app.muEditFieldLabel.Text = 'mu';
            app.mu = uieditfield(app.GeneralTab, 'numeric');
            app.mu.Value = 1.2566e-06;
            app.NumericalOptionsLabel = uilabel(app.GeneralTab);
            app.NumericalOptionsLabel.Text = 'Numerical Options';
            app.MaxStepEditFieldLabel = uilabel(app.GeneralTab);
            app.MaxStepEditFieldLabel.Text = 'MaxStep';
            app.MaxStep = uieditfield(app.GeneralTab, 'numeric');
            app.MaxStep.Value = 1e-08;
            app.t_finalEditFieldLabel = uilabel(app.GeneralTab);
            app.t_finalEditFieldLabel.Text = 't_final';
            app.t_final = uieditfield(app.GeneralTab, 'numeric');
            app.t_final.Value = 2.5e-06;
            app.AbsTolEditFieldLabel = uilabel(app.GeneralTab);
            app.AbsTolEditFieldLabel.Text = 'AbsTol';
            app.AbsTol = uieditfield(app.GeneralTab, 'numeric');
            app.AbsTol.Value = 1e-06;
            app.RelTolEditFieldLabel = uilabel(app.GeneralTab);
            app.RelTolEditFieldLabel.Text = 'RelTol';
            app.RelTol = uieditfield(app.GeneralTab, 'numeric');
            app.RelTol.Value = 0.0001;
            app.NumericalMethodDropDownLabel = uilabel(app.GeneralTab);
            app.NumericalMethodDropDownLabel.Text = 'Numerical Method';
            app.NumericalMethod = uidropdown(app.GeneralTab);
            app.NumericalMethod.Items = {'ode23', 'ode45', 'ode113'};
            app.NumericalMethod.Value = 'ode45';
            app.ElectricParameterLabel = uilabel(app.GeneralTab);
            app.ElectricParameterLabel.Text = 'Electric Parameter';
            app.LcEditFieldLabel = uilabel(app.GeneralTab);
            app.LcEditFieldLabel.Text = 'Lc';
            app.Lc = uieditfield(app.GeneralTab, 'numeric');
            app.LpeEditFieldLabel = uilabel(app.GeneralTab);
            app.LpeEditFieldLabel.Text = 'Lpe';
            app.Lpe = uieditfield(app.GeneralTab, 'numeric');
            app.LeEditFieldLabel = uilabel(app.GeneralTab);
            app.LeEditFieldLabel.Text = 'Le';
            app.Le = uieditfield(app.GeneralTab, 'numeric');
            app.Le.Value = 3.4e-08;
            app.RcEditFieldLabel = uilabel(app.GeneralTab);
            app.RcEditFieldLabel.Text = 'Rc';
            app.Rc = uieditfield(app.GeneralTab, 'numeric');
            app.Rc.Value = 0.03;
            app.ReEditFieldLabel = uilabel(app.GeneralTab);
            app.ReEditFieldLabel.Text = 'Re';
            app.Re = uieditfield(app.GeneralTab, 'numeric');
            app.RpeEditFieldLabel = uilabel(app.GeneralTab);
            app.RpeEditFieldLabel.Text = 'Rpe';
            app.Rpe = uieditfield(app.GeneralTab, 'numeric');
            app.RpEditFieldLabel = uilabel(app.GeneralTab);
            app.RpEditFieldLabel.Text = 'Rp';
            app.Rp = uieditfield(app.GeneralTab, 'numeric');
            app.Rp.Value = 0.074;
            app.CEditFieldLabel = uilabel(app.GeneralTab);
            app.CEditFieldLabel.Text = 'C';
            app.C = uieditfield(app.GeneralTab, 'numeric');
            app.C.Value = 2e-06;
            app.V0EditFieldLabel = uilabel(app.GeneralTab);
            app.V0EditFieldLabel.Text = 'V0';
            app.V0 = uieditfield(app.GeneralTab, 'numeric');
            app.V0.Value = 1360;
            app.ThrusterGeometryLabel = uilabel(app.GeneralTab);
            app.ThrusterGeometryLabel.Text = 'Thruster Geometry';
            app.hEditFieldLabel = uilabel(app.GeneralTab);
            app.hEditFieldLabel.Text = 'h';
            app.h = uieditfield(app.GeneralTab, 'numeric');
            app.h.Value = 0.03;
            app.wEditFieldLabel = uilabel(app.GeneralTab);
            app.wEditFieldLabel.Text = 'w';
            app.w = uieditfield(app.GeneralTab, 'numeric');
            app.w.Value = 0.01;
            app.lEditFieldLabel = uilabel(app.GeneralTab);
            app.lEditFieldLabel.Text = 'l';
            app.l = uieditfield(app.GeneralTab, 'numeric');
            app.l.Value = 0.006;
            app.r_inLabel = uilabel(app.GeneralTab);
            app.r_inLabel.Text = 'r_in';
            app.r_in = uieditfield(app.GeneralTab, 'numeric');
            app.r_in.Editable = 'off';
            app.r_in.Value = 0.025;
            app.r_outLabel = uilabel(app.GeneralTab);
            app.r_outLabel.Text = 'r_out';
            app.r_out = uieditfield(app.GeneralTab, 'numeric');
            app.r_out.Editable = 'off';
            app.r_out.Value = 0.03;
            app.OthersLabel = uilabel(app.GeneralTab);
            app.OthersLabel.Text = 'Others';
            app.t_pulseEditFieldLabel = uilabel(app.GeneralTab);
            app.t_pulseEditFieldLabel.Text = 't_pulse';
            app.t_pulse = uieditfield(app.GeneralTab, 'numeric');
            app.t_pulse.Value = 4e-07;
            app.deltaEditFieldLabel = uilabel(app.GeneralTab);
            app.deltaEditFieldLabel.Text = 'delta';
            app.delta = uieditfield(app.GeneralTab, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.GeneralTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alpha = uieditfield(app.GeneralTab, 'numeric');
            app.alpha.Value = 1;
            app.m_bitEditFieldLabel = uilabel(app.GeneralTab);
            app.m_bitEditFieldLabel.Text = 'm_bit';
            app.m_bit = uieditfield(app.GeneralTab, 'numeric');
            app.m_bit.Value = 1e-08;
            app.StatusEditFieldLabel = uilabel(app.GeneralTab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.Status = uieditfield(app.GeneralTab, 'text');
            app.n_tLabel = uilabel(app.GeneralTab);
            app.n_tLabel.Text = {'n_t'; ''};
            app.n_t = uieditfield(app.GeneralTab, 'numeric');
            app.n_t.ValueDisplayFormat = '%.5f';
            app.v_eLabel = uilabel(app.GeneralTab);
            app.v_eLabel.Text = 'v_e';
            app.exhaust_v = uieditfield(app.GeneralTab, 'numeric');
            app.exhaust_v.ValueDisplayFormat = '%.5f';
            app.IspLabel = uilabel(app.GeneralTab);
            app.IspLabel.Text = 'Isp';
            app.Isp = uieditfield(app.GeneralTab, 'numeric');
            app.Isp.ValueDisplayFormat = '%.5f';
            app.I_bitEditFieldLabel = uilabel(app.GeneralTab);
            app.I_bitEditFieldLabel.Text = 'I_bit';
            app.I_bit = uieditfield(app.GeneralTab, 'numeric');
            app.I_bit.ValueDisplayFormat = '%.6f';
            app.OtimizationTab = uitab(app.TabGroup);
            app.OtimizationTab.Title = 'Otimization';
            app.ThrusterGeometry_2 = uibuttongroup(app.OtimizationTab);
            app.ThrusterGeometry_2.Title = 'Thruster Geometry';
            app.ParallelPlateButton_2 = uiradiobutton(app.ThrusterGeometry_2);
            app.ParallelPlateButton_2.Text = 'Parallel Plate';
            app.ParallelPlateButton_2.Value = true;
            app.CoaxialButton_2 = uiradiobutton(app.ThrusterGeometry_2);
            app.CoaxialButton_2.Text = 'Coaxial';
            app.ElectricParameterLabel_2 = uilabel(app.OtimizationTab);
            app.ElectricParameterLabel_2.Text = 'Electric Parameter';
            app.LcEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.LcEditFieldLabel_2.Text = 'Lc';
            app.Lc_min = uieditfield(app.OtimizationTab, 'numeric');
            app.LpeEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.LpeEditFieldLabel_2.Text = 'Lpe';
            app.Lpe_min = uieditfield(app.OtimizationTab, 'numeric');
            app.LeEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.LeEditFieldLabel_2.Text = 'Le';
            app.Le_min = uieditfield(app.OtimizationTab, 'numeric');
            app.Le_min.Value = 3.4e-08;
            app.RcEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.RcEditFieldLabel_2.Text = 'Rc';
            app.Rc_min = uieditfield(app.OtimizationTab, 'numeric');
            app.Rc_min.Value = 0.03;
            app.ReEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.ReEditFieldLabel_2.Text = 'Re';
            app.Re_min = uieditfield(app.OtimizationTab, 'numeric');
            app.RpeEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.RpeEditFieldLabel_2.Text = 'Rpe';
            app.Rpe_min = uieditfield(app.OtimizationTab, 'numeric');
            app.RpEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.RpEditFieldLabel_2.Text = 'Rp';
            app.Rp_min = uieditfield(app.OtimizationTab, 'numeric');
            app.Rp_min.Value = 0.074;
            app.CEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.CEditFieldLabel_2.Text = 'C';
            app.C_min = uieditfield(app.OtimizationTab, 'numeric');
            app.C_min.Value = 2e-06;
            app.V0EditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.V0EditFieldLabel_2.Text = 'V0';
            app.V0_min = uieditfield(app.OtimizationTab, 'numeric');
            app.V0_min.Value = 1360;
            app.PlasmaPropertiesLabel_2 = uilabel(app.OtimizationTab);
            app.PlasmaPropertiesLabel_2.Text = 'Plasma Properties';
            app.TeEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.TeEditFieldLabel_2.Text = 'Te';
            app.Te_min = uieditfield(app.OtimizationTab, 'numeric');
            app.Te_min.Value = 1.5;
            app.n_eEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.n_eEditFieldLabel_2.Text = 'n_e';
            app.n_e_min = uieditfield(app.OtimizationTab, 'numeric');
            app.n_e_min.Value = 1e+21;
            app.muEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.muEditFieldLabel_2.Text = 'mu';
            app.mu_min = uieditfield(app.OtimizationTab, 'numeric');
            app.mu_min.Value = 1.2566e-06;
            app.cbLc = uicheckbox(app.OtimizationTab);
            app.cbLc.Text = 'Fixed';
            app.cbLc.Value = true;
            app.cbLe = uicheckbox(app.OtimizationTab);
            app.cbLe.Text = 'Fixed';
            app.cbRe = uicheckbox(app.OtimizationTab);
            app.cbRe.Text = 'Fixed';
            app.cbRe.Value = true;
            app.cbRp = uicheckbox(app.OtimizationTab);
            app.cbRp.Text = 'Fixed';
            app.cbV0 = uicheckbox(app.OtimizationTab);
            app.cbV0.Text = 'Fixed';
            app.cbLpe = uicheckbox(app.OtimizationTab);
            app.cbLpe.Text = 'Fixed';
            app.cbLpe.Value = true;
            app.cbRc = uicheckbox(app.OtimizationTab);
            app.cbRc.Text = 'Fixed';
            app.cbRpe = uicheckbox(app.OtimizationTab);
            app.cbRpe.Text = 'Fixed';
            app.cbRpe.Value = true;
            app.cbC = uicheckbox(app.OtimizationTab);
            app.cbC.Text = 'Fixed';
            app.ThrusterGeometryLabel_2 = uilabel(app.OtimizationTab);
            app.ThrusterGeometryLabel_2.Text = 'Thruster Geometry';
            app.hEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.hEditFieldLabel_2.Text = 'h';
            app.h_min = uieditfield(app.OtimizationTab, 'numeric');
            app.h_min.Value = 0.03;
            app.wEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.wEditFieldLabel_2.Text = 'w';
            app.w_min = uieditfield(app.OtimizationTab, 'numeric');
            app.w_min.Value = 0.01;
            app.lEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.lEditFieldLabel_2.Text = 'l';
            app.l_min = uieditfield(app.OtimizationTab, 'numeric');
            app.l_min.Value = 0.006;
            app.r_inLabel_2 = uilabel(app.OtimizationTab);
            app.r_inLabel_2.Text = 'r_in';
            app.r_in_min = uieditfield(app.OtimizationTab, 'numeric');
            app.r_in_min.Editable = 'off';
            app.r_in_min.Value = 0.025;
            app.r_outLabel_2 = uilabel(app.OtimizationTab);
            app.r_outLabel_2.Text = 'r_out';
            app.r_out_min = uieditfield(app.OtimizationTab, 'numeric');
            app.r_out_min.Editable = 'off';
            app.r_out_min.Value = 0.03;
            app.cbh = uicheckbox(app.OtimizationTab);
            app.cbh.Text = 'Fixed';
            app.cbw = uicheckbox(app.OtimizationTab);
            app.cbw.Text = 'Fixed';
            app.cbl = uicheckbox(app.OtimizationTab);
            app.cbl.Text = 'Fixed';
            app.cbr_in = uicheckbox(app.OtimizationTab);
            app.cbr_in.Text = 'Fixed';
            app.cbr_out = uicheckbox(app.OtimizationTab);
            app.cbr_out.Text = 'Fixed';
            app.OptionsLabel = uilabel(app.OtimizationTab);
            app.OptionsLabel.Text = 'Options';
            app.RangeEditFieldLabel = uilabel(app.OtimizationTab);
            app.RangeEditFieldLabel.Text = 'Range';
            app.range = uieditfield(app.OtimizationTab, 'numeric');
            app.NPointsEditFieldLabel = uilabel(app.OtimizationTab);
            app.NPointsEditFieldLabel.Text = 'Nº Points';
            app.npoints = uieditfield(app.OtimizationTab, 'numeric');
            app.t_finalEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.t_finalEditFieldLabel_2.Text = 't_final';
            app.t_final_min = uieditfield(app.OtimizationTab, 'numeric');
            app.t_final_min.Value = 2.5e-06;
            app.RunButton = uibutton(app.OtimizationTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Lc_max = uieditfield(app.OtimizationTab, 'numeric');
            app.Lpe_max = uieditfield(app.OtimizationTab, 'numeric');
            app.Le_max = uieditfield(app.OtimizationTab, 'numeric');
            app.Le_max.Value = 3.6e-08;
            app.Rc_max = uieditfield(app.OtimizationTab, 'numeric');
            app.Rc_max.Value = 0.08;
            app.Re_max = uieditfield(app.OtimizationTab, 'numeric');
            app.Rpe_max = uieditfield(app.OtimizationTab, 'numeric');
            app.Rp_max = uieditfield(app.OtimizationTab, 'numeric');
            app.Rp_max.Value = 0.1;
            app.C_max = uieditfield(app.OtimizationTab, 'numeric');
            app.C_max.Value = 8e-06;
            app.V0_max = uieditfield(app.OtimizationTab, 'numeric');
            app.V0_max.Value = 1800;
            app.h_max = uieditfield(app.OtimizationTab, 'numeric');
            app.h_max.Value = 0.08;
            app.w_max = uieditfield(app.OtimizationTab, 'numeric');
            app.w_max.Value = 0.05;
            app.l_max = uieditfield(app.OtimizationTab, 'numeric');
            app.l_max.Value = 0.01;
            app.r_in_max = uieditfield(app.OtimizationTab, 'numeric');
            app.r_in_max.Editable = 'off';
            app.r_in_max.Value = 0.025;
            app.r_out_max = uieditfield(app.OtimizationTab, 'numeric');
            app.r_out_max.Editable = 'off';
            app.r_out_max.Value = 0.03;
            app.t_final_max = uieditfield(app.OtimizationTab, 'numeric');
            app.t_final_max.Value = 3.2e-06;
            app.cbt_final = uicheckbox(app.OtimizationTab);
            app.cbt_final.Text = 'Fixed';
            app.MinLabel = uilabel(app.OtimizationTab);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.OtimizationTab);
            app.MaxLabel.Text = 'Max';
            app.DebugLabel = uilabel(app.OtimizationTab);
            app.DebugLabel.Text = 'Debug:';
            app.debugArea = uitextarea(app.OtimizationTab);
            app.m_bitEditFieldLabel_2 = uilabel(app.OtimizationTab);
            app.m_bitEditFieldLabel_2.Text = 'm_bit';
            app.m_bit_min = uieditfield(app.OtimizationTab, 'numeric');
            app.m_bit_min.Value = 1e-08;
            app.m_bit_max = uieditfield(app.OtimizationTab, 'numeric');
            app.m_bit_max.Value = 1.5e-08;
            app.cbm_bit = uicheckbox(app.OtimizationTab);
            app.cbm_bit.Text = 'Fixed';
            app.OtimizationObjectiveListBoxLabel = uilabel(app.OtimizationTab);
            app.OtimizationObjectiveListBoxLabel.Text = 'Otimization Objective';
            app.OtimizationObjectiveListBox = uilistbox(app.OtimizationTab);
            app.OtimizationObjectiveListBox.Items = {'Thruster Efficiency', 'Specific Impulse', 'Thrust/Power'};
            app.OtimizationObjectiveListBox.Value = 'Thruster Efficiency';
            app.NumberofGenerationsEditFieldLabel = uilabel(app.OtimizationTab);
            app.NumberofGenerationsEditFieldLabel.Text = 'Number of Generations:';
            app.numgen = uieditfield(app.OtimizationTab, 'numeric');
            app.numgen.Value = 5;
            app.PopulationSizeEditFieldLabel = uilabel(app.OtimizationTab);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size:';
            app.popsize = uieditfield(app.OtimizationTab, 'numeric');
            app.popsize.Value = 20;
            app.MutationScaleEditFieldLabel = uilabel(app.OtimizationTab);
            app.MutationScaleEditFieldLabel.Text = 'Mutation Scale:';
            app.mutscale = uieditfield(app.OtimizationTab, 'numeric');
            app.mutscale.Value = 0.2;
            app.CrossoverRateEditFieldLabel = uilabel(app.OtimizationTab);
            app.CrossoverRateEditFieldLabel.Text = 'Crossover Rate:';
            app.crossrate = uieditfield(app.OtimizationTab, 'numeric');
            app.crossrate.Value = 0.65;
            app.DEParametersLabel = uilabel(app.OtimizationTab);
            app.DEParametersLabel.Text = 'DE Parameters';
            app.Ablationm_bitTab = uitab(app.TabGroup);
            app.Ablationm_bitTab.Title = 'Ablation (m_bit)';
            app.AblationModelDropDownLabel = uilabel(app.Ablationm_bitTab);
            app.AblationModelDropDownLabel.Text = 'Ablation Model';
            app.AblationModelDropDown = uidropdown(app.Ablationm_bitTab);
            app.AblationModelDropDown.Items = {'Langmuir', 'Knudsen Double Layer'};
            app.AblationModelDropDown.Value = 'Langmuir';
            app.AmbientPressure = uieditfield(app.Ablationm_bitTab, 'numeric');
            app.AmbientPressure.Value = 101000;
            app.PropellantDropDownLabel = uilabel(app.Ablationm_bitTab);
            app.PropellantDropDownLabel.Text = 'Propellant';
            app.PropellantDropDown = uidropdown(app.Ablationm_bitTab);
            app.PropellantDropDown.Items = {'PTFE', 'PE'};
            app.PropellantDropDown.Value = 'PTFE';
            app.SurfaceTemperatureKEditFieldLabel = uilabel(app.Ablationm_bitTab);
            app.SurfaceTemperatureKEditFieldLabel.Text = 'Surface Temperature (K)';
            app.SurfaceTemperature = uieditfield(app.Ablationm_bitTab, 'numeric');
            app.SurfaceTemperature.Value = 1000;
            app.CalculateButton = uibutton(app.Ablationm_bitTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Estimatedm_bitkgLabel = uilabel(app.Ablationm_bitTab);
            app.Estimatedm_bitkgLabel.Text = 'Estimated m_bit (kg)';
            app.estimated_m_bit = uieditfield(app.Ablationm_bitTab, 'numeric');
            app.SurfaceAream2Label = uilabel(app.Ablationm_bitTab);
            app.SurfaceAream2Label.Text = 'Surface Area (m^2)';
            app.SurfaceArea = uieditfield(app.Ablationm_bitTab, 'numeric');
            app.SurfaceArea.Value = 0.0003;
            app.PulseTimesEditFieldLabel = uilabel(app.Ablationm_bitTab);
            app.PulseTimesEditFieldLabel.Text = 'Pulse Time (s)';
            app.PulseTime = uieditfield(app.Ablationm_bitTab, 'numeric');
            app.PulseTime.Value = 2.5e-06;
            app.SpecifyProperty = uibuttongroup(app.Ablationm_bitTab);
            app.SpecifyProperty.SelectionChangedFcn = createCallbackFcn(app, @SpecifyPropertySelectionChanged, true);
            app.SpecifyProperty.Title = 'Specify one property:';
            app.AmbientPressureButton = uitogglebutton(app.SpecifyProperty);
            app.AmbientPressureButton.Text = 'Ambient Pressure';
            app.AmbientPressureButton.Value = true;
            app.ElectronDensityButton = uitogglebutton(app.SpecifyProperty);
            app.ElectronDensityButton.Text = 'Electron Density';
            app.AmbientPressureLabel = uilabel(app.Ablationm_bitTab);
            app.AmbientPressureLabel.Text = 'Ambient Pressure (Pa)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ppt_design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NumPPTGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NumPPTGUIFigure)
        end
    end
end